classdef QCTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BeginMenu             matlab.ui.container.Menu
        input                 matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        RefreshButton         matlab.ui.control.Button
        QC                    matlab.ui.control.DropDown
        GenerateReportButton  matlab.ui.control.Button
        SI                    matlab.ui.control.DropDown
        System                matlab.ui.control.DropDown
        TabGroup              matlab.ui.container.TabGroup
        StatisTab             matlab.ui.container.Tab
        GridLayout2           matlab.ui.container.GridLayout
        UITable               matlab.ui.control.Table
        Label4                matlab.ui.control.Label
        Label3                matlab.ui.control.Label
        GPSTLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data                  %数据结构体
        statis                %数据完整率统计
        Ratio                 %数据完整率
        res                   %Oslps值、MP1、MP2
        Error                 %粗差观测历元
        Slip                  %周跳观测历元
        MP                    %MP时间序列值
    end
    
    methods (Access = private)
        %fuc1**************************************************************
        function OutStruct=read_R_O(input1,input2)
%本函数用于读取Rinex格式的O文件，输出一个嵌套结构体
%input1为文件路径或者文件名称
%input2为选取系统，如input2='GC',则输出的结构体中只含GPS、BDS中的数据信息，不含其他卫星系统数据
%G=GPS R=GLONASS C=COMPASS S=SBAS J=QZSS E=GALILEO -----------注
%input3为选取观测值类型，如input3='CL',则输出的结构体中只含伪距和载波相位观测值
%C=码伪距观测值 L=载波相位观测值 D=多普勒观测值 S=信噪比观测值 --------注

%OutStruct: {Header:[1*1 struct] G:[1*1 struct] R:[1*1 struct] C:[1*1 struct]}
%OutStruct中包括Header结构体和包括每个GNSS系统观测值的结构体
%对于每个GNSS系统 以C为例(有C01、C02、C03等多颗卫星）
%C: {C01: [1*1 struct] C02: {1*1 struct} ......}
%对于BDS中每颗特定的卫星，以C01为例(有B1、B2、B3三个频率的载波,和相应的观测历元）
%C01: {B1:[1*1 struct] B2:[1*1 struct] B3:[1*1 struct] Epoch:[n*1 int]}
%对于每个载波，以B1载波为例(总共有4种类型的观测量，每种类型的观测量还包括强度)
%B1:{C: [n*1 double] L: [n*1 double] D: [n*1 double] S: [n*1 double]}
%n为历元数，4行数据分别为伪距、载波、多普勒、信噪比(也可根据input3的内容缩减，但顺序不变）

%目前只支持读取GPS、GLONASS、BDS系统的伪距和载波相位观测值(可添加）
%end of header
%OutStruct=read_R_O('4_1_WG017_1.22O','GRC')

%为输出结构体添加GNSS系统信息
OutStruct=struct('Header',struct);
for i=1:length(input2)
    OutStruct.(input2(i))=struct;
end

%略过文件头部分（后续可添加读取文件头信息内容）
file=fopen(input1);
k=1;
while(k)
    line=fgetl(file);
    %添加采样间隔信息
    if contains(splitstrbynum(line,[61 length(line)]),'INTERVAL')
        OutStruct.Header.Interval=str2num(splitstrbynum(line,[1 60]));
    end
    %添加起始观测、结束观测时间信息
    if contains(splitstrbynum(line,[61 length(line)]),'TIME OF FIRST OBS')
        time_start=str2num(splitstrbynum(line,[1 48]));
        OutStruct.Header.TimeOfFirstObs=time_start;
    end
    if contains(splitstrbynum(line,[61 length(line)]),'TIME OF LAST OBS')
        time_end=str2num(splitstrbynum(line,[1 48]));
        OutStruct.Header.TimeOfLastObs=time_end;
    end
    %判断文件头是否结束
    if contains(line,'END OF HEADER')
        k=0;
    end
end
%读取文件主体
Epoch=0;%历元计数
while(~feof(file))
    line=fgetl(file);
    if contains(line,'>')
        Epoch=Epoch+1;%每读取到一个'>'，表示历元数加一
        line=fgetl(file);
    end
    Sys=line(1);SI=line(1:3);%卫星系统标识信息
    if contains(input2,Sys)
        OutStruct=read_R_O_line(OutStruct,line);%更新卫星观测值信息
        if ~isfield(OutStruct.(Sys).(SI),'Epoch')%如果该卫星未添加历元信息，则为它添加
        OutStruct.(Sys).(SI)=setfield(OutStruct.(Sys).(SI),'Epoch',[]);
        end
        %更新卫星历元信息
        OutStruct.(Sys).(SI).Epoch=[OutStruct.(Sys).(SI).Epoch;Epoch];
    end
end   
        
        function results = func6(app)
            
        end
        end
        %fuc2**************************************************************
        function OutStruct=read_R_O_line(instruct,line)
%读取观测文件主体中的一行数据
%
%三频信号，数据(每个数据包括观测值和强度，他们之间用空格隔开)排列顺序如下:
%  卫星号 载波L1观测值  C1      L1       D1       S1
%   1-3             5-19    21-35    37-51    53-67
%        载波L2观测值  C2      L2       D2       S2
%                   69-83   85-99    101-115  117-131
%        载波L3观测值  C3      L3       D3       S3
%                   133-147 149-163  165-179  181-195
%
%目前支持读取GPS、GLONASS、BDS中的观测量
%OutStruct=read_R_O_line(OutStruct,line)
Sys=line(1);SI=line(1:3);%卫星标识信息
if ~isfield(instruct.(Sys),SI)
   instruct.(Sys).(SI)=struct;%如果尚未添加这一卫星，则添加这颗卫星
   %为这颗卫星添加相应载波，及观测值
   switch Sys
       case {'G','R'}
           instruct.(Sys).(SI).L1=struct('C',[],'L',[],'D',[],'S',[]);
           instruct.(Sys).(SI).L2=struct('C',[],'L',[],'D',[],'S',[]);
       case 'C'
           instruct.(Sys).(SI).B1=struct('C',[],'L',[],'D',[],'S',[]);
           instruct.(Sys).(SI).B2=struct('C',[],'L',[],'D',[],'S',[]);
           instruct.(Sys).(SI).B3=struct('C',[],'L',[],'D',[],'S',[]);
   end
end
%为不同系统的卫星不同波段载波读入数据
order1=[5 19 21 35 37 51 53 67 69 83 85 99 101 115 117 131];%双频切割序列
order2=[order1 133 147 149 163 165 179 181 195];%三频切割序列
if length(line)<=131%双频情况
    Obs_str=splitstrbynum(line,order1);%分割数据
    %将Obs_str中没有的观测量标记，而后用0代替
    Obs=[];
    [m,n]=size(Obs_str);
    for i=1:m
        if isspace(Obs_str(i,:))
            %没有观测值时，将字符串首尾各加入一个0，表示没有观测值或者强度信息
            Obs_str(i,1)='0';
            Obs_str(i,end)='0';
        end
        Obs_str_=splitstrbynum(Obs_str(i,:),[1 13 14 15]);%将观测值与强度分开
        Obs=[Obs;double(str2num(Obs_str_))'];
    end
    instruct.(Sys).(SI).L1.C=[instruct.(Sys).(SI).L1.C;Obs(1,:)];
    instruct.(Sys).(SI).L1.L=[instruct.(Sys).(SI).L1.L;Obs(2,:)];
    instruct.(Sys).(SI).L1.D=[instruct.(Sys).(SI).L1.D;Obs(3,:)];
    instruct.(Sys).(SI).L1.S=[instruct.(Sys).(SI).L1.S;Obs(4,:)];
    instruct.(Sys).(SI).L2.C=[instruct.(Sys).(SI).L2.C;Obs(5,:)];
    instruct.(Sys).(SI).L2.L=[instruct.(Sys).(SI).L2.L;Obs(6,:)];
    instruct.(Sys).(SI).L2.D=[instruct.(Sys).(SI).L2.D;Obs(7,:)];
    instruct.(Sys).(SI).L2.S=[instruct.(Sys).(SI).L2.S;Obs(8,:)];
elseif length(line)<=195 && length(line)>131%三频情况
    Obs_str=splitstrbynum(line,order2);%分割数据
    [m,n]=size(Obs_str);
    Obs=[];
    %将Obs_str中没有的观测量标记，而后用0代替
    for i=1:m
        if isspace(Obs_str(i,:))
            %没有观测值时，将字符串首尾各加入一个0，表示没有观测值或者强度信息
            Obs_str(i,1)='0';
            Obs_str(i,end)='0';
        end
        Obs_str_=splitstrbynum(Obs_str(i,:),[1 13 15 15]);%将观测值与强度分开
        Obs=[Obs;double(str2num(Obs_str_))'];
    end
    instruct.(Sys).(SI).B1.C=[instruct.(Sys).(SI).B1.C;Obs(1,:)];
    instruct.(Sys).(SI).B1.L=[instruct.(Sys).(SI).B1.L;Obs(2,:)];
    instruct.(Sys).(SI).B1.D=[instruct.(Sys).(SI).B1.D;Obs(3,:)];
    instruct.(Sys).(SI).B1.S=[instruct.(Sys).(SI).B1.S;Obs(4,:)];
    instruct.(Sys).(SI).B2.C=[instruct.(Sys).(SI).B2.C;Obs(5,:)];
    instruct.(Sys).(SI).B2.L=[instruct.(Sys).(SI).B2.L;Obs(6,:)];
    instruct.(Sys).(SI).B2.D=[instruct.(Sys).(SI).B2.D;Obs(7,:)];
    instruct.(Sys).(SI).B2.S=[instruct.(Sys).(SI).B2.S;Obs(8,:)];
    instruct.(Sys).(SI).B3.C=[instruct.(Sys).(SI).B3.C;Obs(9,:)];
    instruct.(Sys).(SI).B3.L=[instruct.(Sys).(SI).B3.L;Obs(10,:)];
    instruct.(Sys).(SI).B3.D=[instruct.(Sys).(SI).B3.D;Obs(11,:)];
    instruct.(Sys).(SI).B3.S=[instruct.(Sys).(SI).B3.S;Obs(12,:)];  
end
OutStruct=instruct;
        end
        %fuc3**************************************************************
        function Output=splitstrbynum(str,order)
%按照位数截取字符串,用于截取信息全为数据的字符串
%order=[1 3 5 19]时，Output=[str(1:3);str(5:19)];
%order应升序排列,对于空字符段，用O表示
Output=[];
if rem(length(order),2)~=0
    error('order数组应含偶数个元素！');
end
for i=1:2:(length(order)-1)
    if order(i)>length(str)
        splitstr=0;
    else
        splitstr=str(order(i):order(i+1));
    end
    Output=strvcat(Output,splitstr);%将不同长度的splitstr按统一长度存入字符串组
end
        end
        %fuc4**************************************************************
        function [statis,Ratio]=RATIO(Data)
%计算数据完整率RATIO值函数
%输入Data为读入的含有观测信息的结构体，输出含数据完整率信息的结构体
%res为储存数据量、Ratio储存完整率
%[res,Ratio]=RATIO(OutStruct)

%去掉Header字段
Data=rmfield(Data,'Header');
fields1=fieldnames(Data);

%循环统计数据
for i=1:length(fields1)
    statis.(fields1{i})=struct;%添加卫星系统
    
    fields2=fieldnames(Data.(fields1{i}));
    Total=[];%理论观测数据量统计
    Effective=[];%有效观测量统计
    for j=1:length(fields2)%具体到某个卫星
        statis.(fields1{i}).(fields2{j})=struct;%添加卫星
        Total_epoch=length(Data.(fields1{i}).(fields2{j}).Epoch);%该卫星所有观测历元数
        %去除每个卫星的Epoch字段后，便于遍历
        Data.(fields1{i}).(fields2{j})=rmfield(Data.(fields1{i}).(fields2{j}),'Epoch');
        fields3=fieldnames(Data.(fields1{i}).(fields2{j}));
        Total=[Total;Total_epoch];%记录该卫星系统所有卫星的理论观测值总数
        statis.(fields1{i}).(fields2{j}).Epoch=Total_epoch;%记录该卫星理论历元数
        Real=zeros(Total_epoch,length(fields3));%统计有效观测数
        for k=1:length(fields3)
            for m=1:Total_epoch
                if Data.(fields1{i}).(fields2{j}).(fields3{k}).C(m,1)~=0 && Data.(fields1{i}).(fields2{j}).(fields3{k}).L(m,1)~=0
                    Real(m,k)=1;
                end
            end
            statis.(fields1{i}).(fields2{j}).(fields3{k})=sum(Real(:,k));
            %统计该卫星系统在每个单频点下的总有效观测量
            if ~isfield(statis.(fields1{i}),fields3{k})
                statis.(fields1{i}).(fields3{k})=0;
            end
            statis.(fields1{i}).(fields3{k})=statis.(fields1{i}).(fields3{k})+sum(Real(:,k));
        end
        %统计单个卫星有效观测数据(每个频率都需完整观测）
        single_s=[];%记录每个完整观测的历元数
        for t=1:Total_epoch
            if Real(t,:)==ones(1,length(fields3))
                single_s=[single_s;1];
            else
                single_s=[single_s;0];
            end
        end
        Effective=[Effective;sum(single_s)];
        statis.(fields1{i}).(fields2{j}).Total=single_s;
    end
    statis.(fields1{i}).Epoch=sum(Total);
    statis.(fields1{i}).Total=sum(Effective);
end
%计算数据完整率
Ratio.Total=(statis.G.Total+statis.R.Total+statis.C.Total)/(statis.G.Epoch+statis.R.Epoch+statis.C.Epoch);
for i=1:length(fields1)
    if isequal(fields1{i},'G') || isequal(fields1{i},'R')
        Ratio.(fields1{i}).L1=statis.(fields1{i}).L1/statis.(fields1{i}).Epoch;
        Ratio.(fields1{i}).L2=statis.(fields1{i}).L2/statis.(fields1{i}).Epoch;
    else
        Ratio.(fields1{i}).B1=statis.(fields1{i}).B1/statis.(fields1{i}).Epoch;
        Ratio.(fields1{i}).B2=statis.(fields1{i}).B2/statis.(fields1{i}).Epoch;
        Ratio.(fields1{i}).B3=statis.(fields1{i}).B3/statis.(fields1{i}).Epoch;
    end
    Ratio.(fields1{i}).Total=statis.(fields1{i}).Total/statis.(fields1{i}).Epoch;
end
        end
        %fuc5**************************************************************
        function [res,Error,Slip,MP]=Obs_slps_MP(Data,statis,SI)
%[res,new_data]=Obs_slps_MP(Data,statis,SI)
%计算每周跳观测量函数
%输入Data为读入的观测数据结构体，statis为关于观测数据完整率统计信息结构体
%SI为卫星系统和卫星号如PRN='G01'
%输出res包括O/slps值、和多路径误差MP值
%如果SI='All'，则输出所有卫星的O/slps值、和多路径误差MP值，并形成结构体
%Error表示有粗差的历元、Slip表示有周跳的历元（用1标记）


%导入频率、光速等常量
load('constant.mat');
if isequal(SI,'All')
    %添加计算所有卫星O/slps值和MP值的代码
    
elseif ~isfield(Data.(SI(1)),SI) || ~isfield(Data,SI(1))
    error('该卫星或该卫星系统不在观测数据内');
elseif isequal(SI(1),'G') || isequal((SI(1)),'R')
    %添加计算GPS或者GLONASS单颗卫星O/slps值和MP值的代码
    Epoch=statis.(SI(1)).(SI).Total;%有效观测历元
    Obs_1=[Data.(SI(1)).(SI).L1.C(:,1) Data.(SI(1)).(SI).L1.L(:,1)];%L1观测值
    Obs_2=[Data.(SI(1)).(SI).L2.C(:,1) Data.(SI(1)).(SI).L2.L(:,1)];%L2观测值
    %载波L1,L2频率
    if isequal(SI(1),'G')
        f1=fL1;f2=fL2;
    else
        f=slot2frq(SI);
        f1=f(1);f2=f(2);
    end
    %计算Oslps值、MP值
    Obs=[Obs_1 Obs_2];F=[f1 f2];
    [res,Error,Slip,MP]=Cycleslips(Obs,F,Epoch);
else
    %添加计算BDS单颗卫星O/slps值和MP值的代码
    Epoch=statis.(SI(1)).(SI).Total;%有效观测历元
    Obs_1=[Data.(SI(1)).(SI).B1.C(:,1) Data.(SI(1)).(SI).B1.L(:,1)];%B1观测值
    Obs_2=[Data.(SI(1)).(SI).B2.C(:,1) Data.(SI(1)).(SI).B2.L(:,1)];%B2观测值
    f1=fB1l;f2=fB2l;
    Obs=[Obs_1 Obs_2];F=[f1 f2];
    [res,Error,Slip,MP]=Cycleslips(Obs,F,Epoch);
end
        end
        %fuc6**************************************************************
        function [res,Error,Slip,MP]=Cycleslips(Obs,F,Epoch)
%[res,Error,Slip]=Cycleslips(Obs,F,Epoch)
%基于MW组合和GF组合对双频伪距、载波相位观测值进行周跳的探测与修复,F为双频频率
%Epoch为有效观测历元
%输入观测值矩阵，输出双频模糊度
%输出res为：[o_slps MP1 MP2] 
%输出Error为 有粗差的历元 输出Slip为发生周跳的历元 MP为有效、无粗差、无周跳历元的多路径值

%[res,Slip]=Cycleslips(Obs,F);
load('constant.mat')
f1=F(1);f2=F(2);g=f1/f2;
P1=Obs(:,1);L1=Obs(:,2);P2=Obs(:,3);L2=Obs(:,4);
MW=(f1-f2)/c*(f1*P1+f2*P2)/(f1+f2)-(L1-L2);%MW=N1-N2
MW=MW.*Epoch;
GF=c/f1*L1-c/f2*L2;%电离层残差组合
GF=GF.*Epoch;

Error=zeros(length(Epoch),1);%记录发生粗差的历元
Slip=zeros(length(Epoch),1);%记录发生周跳的历元
MP=zeros(length(Epoch),2);%有效、无粗差、无周跳历元的多路径值
%控制参数
n=3;dmax1=1;dmax2=0.05;

k=0;
if sum(Epoch)==0
    res=NaN;
    Error=NaN;
    Slip=NaN;
    MP=NaN;
else
%避免一开始就是无效观测历元，无法初始化
while k<=length(Epoch)
    if Epoch(k+1)==0
        k=k+1;
    else
        break;
    end
end
i=k+1;%从不是无效观测的第一个历元开始平滑
while i<=length(MW)-2
    %MW组合平滑处理
    %如果平滑过程中遇到无效观测值，直接令这个MW值为前一历元的平滑值
    if MW(i)==0
        MW(i)=Average_MW(i-1);
    end
    if i==(k+1)
        Average_MW(i)=MW(i);
        sigma02(i)=dmax1;%MW组合初始阈值设为1
    else
        Average_MW(i)=(i-1)/i*Average_MW(i-1)+1/i*MW(i);
        sigma02(i)=(i-1)/i*sigma02(i-1)+1/i*(MW(i)-Average_MW(i-1))^2;
        
    end
    %判断粗差、周跳
    if abs(MW(i+1)-Average_MW(i))>n*sqrt(sigma02(i)) && Epoch(i+1)~=0%i+1历元无效观测时，不需判断
        if abs(MW(i+2)-Average_MW(i))>n*sqrt(sigma02(i)) && abs(MW(i+2)-MW(i+1))<n*sqrt(sigma02(i)) &&Epoch(i+2)~=0
            Slip(i+1)=1;
            %有周跳，则下一历元的MW值不参与平滑
            MW(i+1)=Average_MW(i);
        elseif abs(MW(i+2)-Average_MW(i))<n*sqrt(sigma02(i)) && abs(MW(i+2)-MW(i+1))>n*sqrt(sigma02(i)) && Epoch(i+2)~=0
            Error(i+1)=1;
            MW(i+1)=Average_MW(i);%将有粗差的MW值直接调为前面i个历元MW值的平滑值
        elseif abs(MW(i+2)-Average_MW(i))>n*sqrt(sigma02(i)) && abs(MW(i+2)-MW(i+1))>n*sqrt(sigma02(i)) && Epoch(i+2)~=0
            %如果连续两个历元都和前面的平滑值相差很远，且不是周跳，则认定这两个历元都为粗差
            Error(i+1)=1;Error(i+2)=1;
            %将有粗差的MW值直接调为前面i个历元MW值的平滑值
            MW(i+1)=Average_MW(i);
            MW(i+2)=Average_MW(i);
        end
    elseif Epoch(i+2)==0%如果i+1历元超限，且i+2历元为无效观测，直接认定i+1历元为周跳
        Slip(i+1)=1;
        MW(i+1)=Average_MW(i);
    end
    i=i+1;
end
%使用GF组合对周跳进行进一步探测
if sum(Slip)==0%如果MW组合未检测到周跳
    i=1;
    while i<=length(Epoch)-1
        if GF(i)~=0 && GF(i+1)~=0 && abs(GF(i+1)-GF(i))>dmax2
            if Error(i)==0 && Error(i+1)==0  %若果无粗差，则认定为周跳
                Slip(i+1)=1;
            end
        elseif GF(i)~=0 && GF(i+1)~=0 && abs(GF(i+1)-GF(i))<dmax2
            %如果不超限，反而有粗差，则去除粗差标记
            if Error(i)~=0 || Error(i+1)~=0
                Error(i)=0;
                Error(i+1)=0;
            end
                
        end
        %循环变量加一
        i=i+1;
    end
else
    %如果MW组合检测到周跳
    Stop=find(Slip);%在MW组合探测到周跳的地方停止
for k=0:length(Stop)
    %判断GF组合历元段
    if k==0
        i=1;j=Stop(1);
    elseif k>0 && k<length(Stop)
        i=Stop(k);j=Stop(k+1);
    else
        i=Stop(end);j=Epoch(end);
    end
    while i<=j-2
        if GF(i)~=0 && GF(i+1)~=0 && abs(GF(i+1)-GF(i))>dmax2
            if Error(i)==0 && Error(i+1)==0  %若果无粗差，则认定为周跳
                Slip(i+1)=1;
            end
        elseif GF(i)~=0 && GF(i+1)~=0 && abs(GF(i+1)-GF(i))<dmax2
            %如果不超限，反而有粗差，则去除粗差标记
            if Error(i)~=0 || Error(i+1)~=0
                Error(i)=0;
                Error(i+1)=0;
            end
                
        end
        %循环变量加一
        i=i+1;
    end
end  
end

%%每周跳观测量
if sum(Slip)~=0
    Oslps=sum(Epoch)/sum(Slip);
else
    Oslps=9999999;
end

MP1=[];MP2=[];%伪距多路径影响值
if sum(Slip)==0%如果无周跳
    i=1;
    while i<=length(Epoch)
         if Error(i)==0 && Epoch(i)~=0%无粗差历元和有效历元
           m1=P1(i)-(g^2+1)/(g^2-1)*c/f1*L1(i)+2/(g^2-1)*c/f2*L2(i);
           MP1=[MP1;m1];
           m2=P2(i)-2*g^2/(g^2-1)*c/f1*L1(i)+(g^2+1)/(g^2-1)*c/f2*L2(i);
           MP2=[MP2;m2];
           MP(i,:)=[m1 m2];
         end
         i=i+1;
    end
    MP1=MP_hard(MP1,Oslps);
    MP2=MP_hard(MP2,Oslps);
    res=[Oslps MP1 MP2];
else
    %如果有周跳
Stop=find(Slip);%在探测到周跳的地方停止
for k=0:length(Stop)
    %判断无周跳历元段
    if k==0
        i=1;j=Stop(1)-1;
    elseif k>0 && k<length(Stop)
        i=Stop(k);j=Stop(k+1)-1;
    elseif k==length(Stop)
        i=Stop(end);j=length(Epoch);
    end
    mp1=[];mp2=[];
    while i<=j-1
         if Error(i)==0 && Epoch(i)~=0%无粗差历元、和有效历元
           m1=P1(i)-(g^2+1)/(g^2-1)*c/f1*L1(i)+2/(g^2-1)*c/f2*L2(i);
           mp1=[mp1;m1];
           m2=P2(i)-2*g^2/(g^2-1)*c/f1*L1(i)+(g^2+1)/(g^2-1)*c/f2*L2(i);
           mp2=[mp2;m2];
           MP(i,:)=[m1 m2];
         end
         i=i+1;
    end
    MP1=[MP1;MP_hard(mp1,Oslps)];
    MP2=[MP2;MP_hard(mp2,Oslps)];
    
end
MP1=sum(MP1)/length(MP1);
MP2=sum(MP2)/length(MP2);
res=[Oslps MP1 MP2];
end
end
        end
        %fuc7**************************************************************
        function res=MP_hard(input,Oslps)
%res=MP(input,Oslps)
%根据各个历元的MP值，计算多路径误差评估值
%对于长度小于Oslps的观测时段，不用，输出为空
if isempty(input)
    res=[];
else
    %剔除粗差
N=length(input);
if N<=sqrt(Oslps) && Oslps<9999999
    res=[];
else 
    MP=0;
    for i=1:N
        MP=(input(i)-sum(input)/N)^2+MP;
    end
    res=sqrt(MP/(N-1));
end
end
        end
        
        %fuc8**************************************************************
        function [file,path]=getObsfile()
%专门用于读取RENIX格式的O文件
%[file path]=getObsfile()
%年份暂时限定为05-25
str='*.05O;*.06O;*.07O;*.08O;*.09O;*.10O;*.11O;*.12O;*.13O;*.14O;*.15O;*.16O;*.17O;*.18O;*.19O;*.20O;*.21O;*.22O;*.23O;*.24O;*.25O';
[file,path]=uigetfile(str,'选取O文件');
        end
        
        %fuc9**************************************************************
        function frq=slot2frq(SI)
%根据GLONASS卫星的SI号，来计算相应L1、L2载波的频率
%输入例如R10,输出频率(以Hz为单位）
slot=0;%插槽
number=str2num(SI(2:3));%卫星号
switch number
    case 1
        slot=1;
    case 2
        slot=-4;
    case 3
        slot=5;
    case 4
        slot=6;
    case 5
        slot=1;
    case 6
        slot=-4;
    case 7
        slot=5;
    case 8
        slot=6;
    case 9
        slot=-2;
    case 10
        slot=-7;
    case 11
        slot=-5;
    case 12
        slot=-1;
    case 13
        slot=-2;
    case 14
        slot=-7;
    case 15
        slot=0;
    case 16
        slot=-1;
    case 17
        slot=4;
    case 18
        slot=-3;
    case 19
        slot=3;
    case 20
        slot=2;
    case 21
        slot=4;
    case 22
        slot=-3;
    case 23
        slot=3;
    case 24
        slot=2;
end
f1=1602.5625e6+(slot-1)*0.5625e6;
f2=1246.4375e6+(slot-1)*0.4375e6;
frq=[f1;f2];
        end
        
        %fuc10**************************************************************
        function visible_Ratio(Data,statis,Identity,bool)
%visble_Ratio(OutStruct,statis,'C',1)
%根据Data、statis中的数据、Identiy标识
%bool等于1时，加上周跳、粗差等信息
%清空之前所有图像
h=findobj;
delete(h);
if length(Identity)==1
    %确定文件头信息
    interval=Data.Header.Interval/(24*3600);
    t1=Data.Header.TimeOfFirstObs(4:6);
    t2=Data.Header.TimeOfLastObs(4:6);
    %str格式
    t1=[num2str(t1(1)),':',num2str(t1(2)),':',num2str(t1(3))];
    t2=[num2str(t2(1)),':',num2str(t2(2)),':',num2str(t2(3))];
    %datenum
    t1=datenum(t1);t2=datenum(t2);
    %做出整个系统的图
    fields=fieldnames(Data.(Identity));
    for i=1:length(fields)
        Epoch=Data.(Identity).(fields{i}).Epoch;
        %把相应观测历元改为时间
        for j=1:length(Epoch)
            Epoch(j)=t1+Epoch(j)*interval;
        end
        %令无效观测历元时间等于零
        reliable=statis.(Identity).(fields{i}).Total;
        ti=Epoch.*reliable;
        %删除零元素
        ti(ti==0)=[];
        y=i*ones(length(ti),1);
        subplot(1,1,1);
        scatter(ti,y,25,'filled','yellow');
        hold on;
    end
    %添加周跳、粗差等信息
    if bool==1
        for i=1:length(fields)
            %获取粗差、周跳等信息
            [res,Error,Slip,MP]=Obs_slps_MP(Data,statis,fields{i});
            Epoch=Data.(Identity).(fields{i}).Epoch;
            %把相应观测历元改为时间
            for j=1:length(Epoch)
               Epoch(j)=t1+Epoch(j)*interval;
            end
            %删除零元素
            Error=Epoch.*Error;Error(Error==0)=[];
            Slip=Epoch.*Slip;Slip(Slip==0)=[];
            y1=i*ones(length(Error),1);
            y2=i*ones(length(Slip),1);
            scatter(Error,y1,'*','red');
            scatter(Slip,y2,'+','black');
        end
    end
    %改变x、y轴的刻度显示
    yticks(1:length(fields));
    yticklabels(fields);
    datetick('x','HH:MM:SS');
    title('卫星有效观测时段图(*表示粗差,+表示周跳)');
    %要先画图，然后再限制坐标范围
    xlim([t1 t2]);
else
    %单卫星图,画出L1、L2载波完整率和卫星完整率
    Epoch=Data.(Identity(1)).(Identity).Epoch;
    reliable=statis.(Identity(1)).(Identity).Total;
    if isequal(Identity(1),'G') || isequal(Identity(1),'R')
        L1=statis.(Identity(1)).(Identity).L1;
        L2=statis.(Identity(1)).(Identity).L1;
    else
        L1=statis.(Identity(1)).(Identity).B1;
        L2=statis.(Identity(1)).(Identity).B2;
    end
    y=[L1/length(Epoch) L2/length(Epoch) sum(reliable)/length(Epoch)];
    x=[1 3 5];
    xticklabel={'L1','L2','RATIO'};
    str='卫星完整率(L1/L2/总)';
    %添加粗差率、周跳率
    if bool==1
        [res,Error,Slip,MP]=Obs_slps_MP(Data,statis,Identity);
        if isempty(reliable)
            y=[y 0 0];
        else
            y4=sum(Error)/length(reliable);
            if isempty(Slip)
                y5=1;
            else
                y5=1/res(1);
            end
            y=[y y4 y5];
        end
        x=[x 7 9];
        xticklabel={'L1','L2','RATIO','Error' 'Slip'};
        str='卫星完整率(L1/L2/总) 粗差率/周跳率';
    end
    labels=string(y);
    subplot(1,1,1);
    bar(x,y,0.2)
    %添加条形的值
    text(x,y,labels,'HorizontalAlignment','center',...
    'VerticalAlignment','bottom')
    title(str);
    %添加x轴刻度标签
    xticks(x);
    xticklabels(xticklabel);
    ylim([0 1.3])
end
hold off
        end
        
        %fuc11**************************************************************
        function visible_MP(Data,statis,Identity)
%展示多个卫星的MP值、或者展示某个卫星的MP值时间序列图
%visible_MP(Data,statis,Identity)
%清空之前所有图像
h=findobj;
delete(h);

if length(Identity)==1
    %多卫星只展示每个卫星的MP1、MP2
    fields=fieldnames(Data.(Identity));
    MPS=[];SIS={};
    for i=1:length(fields)
        SI=fields{i};
        [res,Error,Slip,MP]=Obs_slps_MP(Data,statis,SI);
        %对于MP值大于1.5或者无法计算的波段，将其MP值设为1.5,表示无参考意义
        if isnan(res)
            res(2)=1.5;
            res(3)=1.5;
        elseif length(res)==1
            res=[res(1) 1.5 1.5];
        end
        if res(2)>=1.5
            res(2)=1.5;
        end
        if res(3)>1.5
            res(3)=1.5;
        end
        MPS=[MPS;res(2) res(3)];
        SIS{length(SIS)+1}=SI;
    end
    subplot(1,1,1);
    bar(MPS);
    xticklabels(SIS);
    title('SI-MP1/MP2(m)');
else
    %单卫星展示每个载波的MP序列图
    %加载时间信息
    %确定文件头信息
    interval=Data.Header.Interval/(24*3600);
    t1=Data.Header.TimeOfFirstObs(4:6);
    t2=Data.Header.TimeOfLastObs(4:6);
    %str格式
    t1=[num2str(t1(1)),':',num2str(t1(2)),':',num2str(t1(3))];
    t2=[num2str(t2(1)),':',num2str(t2(2)),':',num2str(t2(3))];
    %datenum
    t1=datenum(t1);t2=datenum(t2);
    Epoch=Data.(Identity(1)).(Identity).Epoch;
    %把相应观测历元改为时间
    for j=1:length(Epoch)
        Epoch(j)=t1+Epoch(j)*interval;
    end
    [res,Error,Slip,MP]=Obs_slps_MP(Data,statis,Identity);
    if isnan(MP) %全为无效观测
        y=zeros(length(Epoch),1);
        plot(Epoch,y,'--oblack');
        legend('无效观测,无法计算MP值');
        str=[Identity '   t(s)-MP(m)'];
        title(str);
        %改变x轴刻度标签，和x、y轴范围
        datetick('x','HH:MM:SS');
        xlim 'tight'
        ylim([-1.5 1.5]);
    elseif isempty(MP)
        y=zeros(length(Epoch),1);
        plot(Epoch,y,'--oblack');
        legend('无效观测,无法计算MP值');
        str=[Identity '   t(s)-MP(m)'];
        title(str);
        %改变x轴刻度标签，和x、y轴范围
        datetick('x','HH:MM:SS');
        xlim 'tight'
        ylim([-1.5 1.5]);
    else
        %有有效观测时段的卫星，MP1和MP2都要做出时间序列图
        L1=MP(:,1);L2=MP(:,2);Oslps=res(1);
        L1=t_MP(L1,Oslps);L2=t_MP(L2,Oslps);
        epoch=Epoch;
        epoch(L2==0)=[];
        reliable_t2=epoch;%L2有效观测时段时间序列
        epoch=Epoch;
        epoch(L1==0)=[];
        reliable_t1=epoch;%L1有效观测时段时间序列
        L1(L1==0)=[];L2(L2==0)=[];%有效观测MP值
        
        subplot(1,2,1)
        if ~isempty(reliable_t1)
            plot(reliable_t1,L1,'-red');
            legend('有效观测');
            str=[Identity '   t(s)-MP1(m)'];
            title(str);
            datetick('x','HH:MM:SS');
            xlim 'tight';
            ylim([-1.5 1.5]);
        else
            plot(Epoch,zeros(length(Epoch),1),'--oblack');
            legend('无效观测,无法计算MP值');
            str=[Identity '   t(s)-MP1(m)'];
            title(str);
            %改变x轴刻度标签，和x、y轴范围
            datetick('x','HH:MM:SS');
            xlim 'tight';
            ylim([-1.5 1.5]);
        end
        
        subplot(1,2,2)
        if ~isempty(reliable_t2)
            plot(reliable_t2,L2,'-red');
            legend('有效观测');
            str=[Identity '   t(s)-MP2(m)'];
            title(str);
            datetick('x','HH:MM:SS');
            xlim 'tight';
            ylim([-1.5 1.5]);
        else
            plot(Epoch,zeros(length(Epoch),1),'--oblack');
            legend('无效观测,无法计算MP值');
            str=[Identity '   t(s)-MP2(m)'];
            title(str);
            %改变x轴刻度标签，和x、y轴范围
            datetick('x','HH:MM:SS');
            xlim 'tight';
            ylim([-1.5 1.5]);
        end
    end 
end
hold off
        end
        
        %fuc11**************************************************************
        function new_MP=t_MP(MP,Oslps)
%new_MP=t_MP(MP,Oslps)
%输入的MP不能为空，不能为全为零
%根据不连续的MP序列值、算出MP时间序列
new_MP=zeros(length(MP),1);
stop=find(MP==0);
if isempty(stop)
    average=sum(MP)/length(MP);
    new_MP=MP-average;
else
    i=0;
    while i<=length(stop)
        if i==0
            m=1;n=stop(1);
        elseif i<length(stop)
            m=stop(i);n=stop(i+1);
        else
            m=stop(end);n=length(MP);
        end
        if n-m>sqrt(Oslps)
            average=sum(MP(m:n))/(n-m);
            new_MP(m:n)=MP(m:n)-average;
        end
        i=i+1;%循环变量
    end
end
%超限出1.5的MP值视为粗差，不使用
new_MP(abs(new_MP)>1.5)=0;
        end
        
        
        %fuc12**************************************************************
        function new_MP=t_MP_easy(MP,Oslps)
%new_MP=t_MP(MP,Oslps)
%输入的MP不能为空，不能为全为零
%根据不连续的MP序列值、算出MP时间序列
new_MP=zeros(length(MP),1);
stop=find(MP==0);
if isempty(stop)
    average=sum(MP)/length(MP);
    new_MP=MP-average;
else
    i=0;
    while i<=length(stop)
        if i==0
            m=1;n=stop(1);
        elseif i<length(stop)
            m=stop(i);n=stop(i+1);
        else
            m=stop(end);n=length(MP);
        end
        if n-m>sqrt(Oslps)
            average=sum(MP(m:n))/(n-m);
            new_MP(m:n)=MP(m:n)-average;
        end
        i=i+1;%循环变量
    end
end
        end
        
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: input
        function inputMenuSelected(app, event)
            [file,path]=getObsfile();
            %导入观测文件数据,计算出各项指标
            app.Data=read_R_O([path file],'GCR');
            [app.statis,app.Ratio]=RATIO(app.Data);
            %设置各个DropDown控件的初始Value
            data=app.Data;
            %设置System
            if isempty(fieldnames(data.G))
                app.System.Items = {'BDS','GLONASS'};
            end
            if isempty(fieldnames(data.R))
                app.System.Items = {'BDS','GPS'};
            end
            if isempty(fieldnames(data.C))
                app.System.Items = {'GPS','GLONASS'};
            end
            %设置SI、QC
            app.System.ValueChangedFcn(app, event)
            app.QC.Value='RATIO';
            %初始化底部信息
            interval=app.Data.Header.Interval;
            t1=app.Data.Header.TimeOfFirstObs;
            t2=app.Data.Header.TimeOfLastObs;
            t1=[num2str(t1(1)),'-',num2str(t1(2)),'-',num2str(t1(3)),' '...
                num2str(t1(4)),':',num2str(t1(5)),':',num2str(t1(6)),'~'];
            t2=[num2str(t2(1)),'-',num2str(t2(2)),'-',num2str(t2(3)),' '...
                num2str(t2(4)),':',num2str(t2(5)),':',num2str(t2(6))];
            app.GPSTLabel.Text=['GPST   ',t1,t2];
            app.Label3.Text=['Interval:',num2str(interval),'s'];
            app.Label4.Text='2690439841@qq.com';


        end

        % Value changed function: System
        function SystemValueChanged(app, event)
            value = app.System.Value;
            %当System的value的值改变时，改变SI的Items和Value
            switch value
                case 'GPS'
                    field1='G';
                case 'GLONASS'
                    field1='R';
                case 'BDS'
                    field1='C';
            end
            data=app.Data;
            fields2=fieldnames(data.(field1));
            fields2{length(fields2)+1}='All';
            app.SI.Items=fields2;
            app.SI.Value='All';
            app.RefreshButton.ButtonPushedFcn(app,event);
            
            
        end

        % Value changed function: SI
        function SIValueChanged(app, event)
            value = app.SI.Value;
            %当SI的Value的值改变时，改变Carrier的值
            app.RefreshButton.ButtonPushedFcn(app,event);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            Sys=app.System.Value;
            SI=app.SI.Value;
            QC=app.QC.Value;
            %导入数据到Table中
            switch Sys
                case 'GPS'
                    system='G';
                case 'GLONASS'
                    system='R';
                case 'BDS'
                    system='C';
            end
            if isequal(SI,'All')
                switch QC
                    case 'RATIO'
                        si=fieldnames(app.Data.(system));
                        rownames=si;
                        rownames(length(rownames)+1)={Sys};
                        colnames={'总历元数','L1有效历元','L1完整率','L2有效历元','L2完整率','有效历元','完整率'};
                        C1=[];C2=[];C3=[];C4=[];C5=[];C6=[];C7=[];
                        for i=1:length(si)
                            C1=[C1;length(app.Data.(system).(si{i}).Epoch)];
                            if isequal(system,'G') || isequal(system,'R')
                                C2=[C2;app.statis.(system).(si{i}).L1];
                                C4=[C4;app.statis.(system).(si{i}).L2];
                                C6=[C6;sum(app.statis.(system).(si{i}).Total)];
                            else
                                C2=[C2;app.statis.(system).(si{i}).B1];
                                C4=[C4;app.statis.(system).(si{i}).B2];
                                C6=[C6;sum(app.statis.(system).(si{i}).Total)];
                            end
                        end
                        C1=[C1;app.statis.(system).Epoch];
                        if isequal(system,'G') || isequal(system,'R')
                            C2=[C2;app.statis.(system).L1];
                            C4=[C4;app.statis.(system).L2];
                            C6=[C6;app.statis.(system).Total];
                        else
                            C2=[C2;app.statis.(system).B1];
                            C4=[C4;app.statis.(system).B2];
                            C6=[C6;app.statis.(system).Total];
                        end
                        C3=C2./C1;
                        C5=C4./C1;
                        C7=C6./C1;
                        T=table(C1,C2,C3,C4,C5,C6,C7,'VariableNames',colnames,'RowNames',rownames);
                        sort=[false false true false true false true];
                        edit=[];
                        mat=[];
                    case 'O/slps'
                        si=fieldnames(app.Data.(system));
                        rownames=si;
                        rownames(length(rownames)+1)={Sys};
                        colnames={'有效历元','周跳历元','O/slps','粗差历元','O/error'};
                        C1=[];C2=[];C3=[];C4=[];C5=[];
                        for i=1:length(si)
                            C1=[C1;sum(app.statis.(system).(si{i}).Total)];
                            [res,Error,Slip,MP]=Obs_slps_MP(app.Data,app.statis,si{i});
                            C2=[C2;sum(Slip)];
                            C4=[C4;sum(Error)];
                            if C1(i)==0
                                C2(i)=0;
                                C4(i)=0;
                            end
                        end
                        C1(length(C1)+1)=sum(C1);
                        C2(length(C2)+1)=sum(C2);
                        C4(length(C4)+1)=sum(C4);
                        for i=1:length(C1)
                            if C2(i)==0 && C1(i)~=0
                                C3=[C3;999999];
                            else
                                C3=[C3;C1(i)/C2(i)];
                            end
                            if C4(i)==0 && C1(i)~=0
                                C5=[C5;999999];
                            else
                                C5=[C5;C1(i)/C4(i)];
                            end
                        end
                        T=table(C1,C2,C3,C4,C5,'VariableNames',colnames,'RowNames',rownames);
                        sort=logical([0 0 1 0 1]);
                        edit=[];
                        mat=[];
                    case 'MP'
                        si=fieldnames(app.Data.(system));
                        rownames=si;
                        rownames(length(rownames)+1)={Sys};
                        colnames={'MP1(m)','MP2(m)'};
                        C1=[];C2=[];
                        sum1=0;sum2=0;m=0;n=0;
                        for i=1:length(si)
                            [res,Error,Slip,MP]=Obs_slps_MP(app.Data,app.statis,si{i})
                            if isnan(res)
                                C1=[C1;nan];
                                C2=[C2;nan];
                            elseif length(res)==1
                                C1=[C1;nan];
                                C2=[C2;nan];
                            else
                                C1=[C1;res(2)];
                                C2=[C2;res(3)];
                                sum1=sum1+res(2);m=m+1;
                                sum2=sum2+res(3);n=n+1;
                            end
                        end
                        C1=[C1;sum1/m];C2=[C2;sum2/n];
                        T=table(C1,C2,'VariableNames',colnames,'RowNames',rownames);
                        sort=logical([1 1]);
                        edit=[];
                        mat={'long','long'};
                end
            else
                switch QC
                    case 'RATIO'
                        n=length(app.Data.(system).(SI).Epoch);
                        rownames=string(1:n);
                        colnames={'Epoch','L1-P(m)','L1-C(cycle)','L2-P(m)','L2-C(cycle)'};
                        C1=app.Data.(system).(SI).Epoch;
                        if isequal(system,'G') || isequal(system,'R')
                            C2=app.Data.(system).(SI).L1.C(:,1);
                            C3=app.Data.(system).(SI).L1.L(:,1);
                            C4=app.Data.(system).(SI).L2.C(:,1);
                            C5=app.Data.(system).(SI).L2.L(:,1);
                        else
                            C2=app.Data.(system).(SI).B1.C(:,1);
                            C3=app.Data.(system).(SI).B1.L(:,1);
                            C4=app.Data.(system).(SI).B2.C(:,1);
                            C5=app.Data.(system).(SI).B2.L(:,1);
                        end
                        T=table(C1,C2,C3,C4,C5,'VariableNames',colnames,'RowNames',rownames);
                        sort=logical([0 1 1 1 1]);
                        edit=[];
                        mat={[],'long','long','long','long'};
                    case 'O/slps'
                        n=length(app.Data.(system).(SI).Epoch);
                        rownames=string(1:n);
                        colnames={'Epoch','Error','Slip'};
                        [res,Error,Slip,MP]=Obs_slps_MP(app.Data,app.statis,SI);
                        C1=app.Data.(system).(SI).Epoch;
                        C2=Error;
                        C3=Slip;
                        T=table(C1,C2,C3,'VariableNames',colnames,'RowNames',rownames);
                        sort=logical([0 1 1]);
                        edit=[];
                        mat=[];
                    case 'MP'
                        n=length(app.Data.(system).(SI).Epoch);
                        rownames=string(1:n);
                        colnames={'Epoch','MP1','MP2'};
                        [res,Error,Slip,MP]=Obs_slps_MP(app.Data,app.statis,SI);
                        C1=app.Data.(system).(SI).Epoch;
                        C2=[];C3=[];
                        if isnan(MP)
                            C2=nan(length(C1),1);
                            C3=nan(length(C1),1);
                        elseif isempty(MP)
                            C2=nan(length(C1),1);
                            C3=nan(length(C1),1);
                        else
                            C2=t_MP(MP(:,1),res(1));
                            C3=t_MP(MP(:,2),res(1));
                        end
                        for i=1:length(C1)
                            if C2(i)==0
                               C2(i)=nan;
                            end
                            if C3(i)==0
                                C3(i)=nan;
                            end
                        end
                        T=table(C1,C2,C3,'VariableNames',colnames,'RowNames',rownames);
                        sort=logical([0 1 1]);
                        edit=[];
                        mat={[],'long','long'};
                end
            end
            %导入Table
            
            app.UITable.Data=table2cell(T);
            app.UITable.ColumnName=T.Properties.VariableNames;
            app.UITable.RowName=T.Properties.RowNames;
            app.UITable.ColumnSortable=sort;
            app.UITable.ColumnEditable=edit;
            app.UITable.ColumnFormat=mat;
            %作图
            if isequal(SI,'All')
                switch Sys
                    case 'GPS'
                        SI='G';
                    case 'GLONASS'
                        SI='R';
                    case 'BDS'
                        SI='C';
                end
            end
            if isequal(QC,'RATIO')
                visible_Ratio(app.Data,app.statis,SI,0)
            elseif isequal(QC,'O/slps')
                visible_Ratio(app.Data,app.statis,SI,1)
            else
                visible_MP(app.Data,app.statis,SI)
            end
        end

        % Value changed function: QC
        function QCValueChanged(app, event)
            value = app.QC.Value;
            app.RefreshButton.ButtonPushedFcn(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BeginMenu = uimenu(app.UIFigure);
            app.BeginMenu.Text = 'Begin';
            app.input = uimenu(app.BeginMenu);
            app.input.MenuSelectedFcn = createCallbackFcn(app, @inputMenuSelected, true);
            app.input.Text = 'import data(o)';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.StatisTab = uitab(app.TabGroup);
            app.StatisTab.Title = 'Statis';
            app.GridLayout2 = uigridlayout(app.StatisTab);
            app.GPSTLabel = uilabel(app.GridLayout2);
            app.GPSTLabel.Text = 'GPST';
            app.Label3 = uilabel(app.GridLayout2);
            app.Label3.Text = '';
            app.Label4 = uilabel(app.GridLayout2);
            app.Label4.Text = '';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.System = uidropdown(app.GridLayout);
            app.System.Items = {'GPS', 'GLONASS', 'BDS'};
            app.System.ValueChangedFcn = createCallbackFcn(app, @SystemValueChanged, true);
            app.System.Value = 'BDS';
            app.SI = uidropdown(app.GridLayout);
            app.SI.Items = {'CO1'};
            app.SI.ValueChangedFcn = createCallbackFcn(app, @SIValueChanged, true);
            app.SI.Value = 'CO1';
            app.GenerateReportButton = uibutton(app.GridLayout, 'push');
            app.GenerateReportButton.Enable = 'off';
            app.GenerateReportButton.Visible = 'off';
            app.GenerateReportButton.Text = 'Generate Report';
            app.QC = uidropdown(app.GridLayout);
            app.QC.Items = {'RATIO', 'O/slps', 'MP'};
            app.QC.ValueChangedFcn = createCallbackFcn(app, @QCValueChanged, true);
            app.QC.Value = 'RATIO';
            app.RefreshButton = uibutton(app.GridLayout, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Visible = 'off';
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end